classdef VectorAutoregression < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VectorAutoregressionModel   matlab.ui.Figure
        PasswordEditFieldLabel      matlab.ui.control.Label
        PasswordEditField           matlab.ui.control.EditField
        VectorautoregressionmodelVARPanel  matlab.ui.container.Panel
        FeaturesLabel               matlab.ui.control.Label
        FeaturesListBox             matlab.ui.control.ListBox
        UIAxes                      matlab.ui.control.UIAxes
        ForecastingPanel            matlab.ui.container.Panel
        ForcastButton               matlab.ui.control.Button
        BacktestforVARMButton       matlab.ui.control.Button
        EstimateUsingVARButton      matlab.ui.control.Button
        pvalueEditFieldLabel        matlab.ui.control.Label
        pvalueEditField             matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        NoForecastPeriodsEditFieldLabel  matlab.ui.control.Label
        NoForecastPeriodsEditField  matlab.ui.control.NumericEditField
        StartoffEditFieldLabel      matlab.ui.control.Label
        StartoffEditField           matlab.ui.control.NumericEditField
        SlidingWindowCheckBox       matlab.ui.control.CheckBox
        EditField                   matlab.ui.control.NumericEditField
        CalculatingErrorsLabel      matlab.ui.control.Label
        ImportProcessedStandardisedDataButton  matlab.ui.control.Button
        ImportProcessedNonStandardisedDataButton  matlab.ui.control.Button
        ShareDropDownLabel          matlab.ui.control.Label
        ShareDropDown               matlab.ui.control.DropDown
        FeatureListButton           matlab.ui.control.Button
    end

    properties (Access = public)       
            TEcon; %This is the actual Data
            TEdates;
            Tedates_num;
            YSeries;
%             labels = {'Save Tcon'}; 
%             vars = {'appTEcon'}; 
%             values = {OLDmutualData};
%             export2wsdlg(labels,vars,values);
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.VectorautoregressionmodelVARPanel.Visible = 'on';
           app.ForecastingPanel.Visible = 'off';
        end

        % Button pushed function: 
        % ImportProcessedStandardisedDataButton
        function ImportProcessedStandardisedDataButtonPushed(app, event)
            load ProcessedStandardised.mat
            OLDmutualData = ProcessedStandardised;
            
            %Change the date format
            formatOut = 'yyyy-mm-dd'; 
            OLDmutualData.DateStamps = datestr(datenum(num2str(OLDmutualData.DateStamps(1:height(OLDmutualData))),'yyyymmdd'),formatOut);
            app.TEcon = OLDmutualData;
            
            %Adds the ShareIDs/Ticker to the Share GUI dropbox
            row = unique(OLDmutualData.Ticker)';            
            app.ShareDropDown.Items = string(row);            
            app.ShareDropDown.Value = 'FBR'; % set a default value (chosen because it has the most amount of data)
        end

        % Button pushed function: ForcastButton
        function ForcastButtonPushed(app, event)
%             val = app.FeaturesListBox.Value;
% 
% 
% %This is how you can save to a workspace
% % labels = {'Save Tcon'}; 
% % vars = {'app.TEcon'}; 
% % values = {app.TEcon};
% % export2wsdlg(labels,vars,values);
% 
%             %drawnow
%             Fin = app.TEcon(1:end-12,:);
%             Fin
%             Find = app.Tedates_num(1:end-12,:);
%             Find
%             Fout = app.TEcon(end-11:end,:);
%             Fout
%             Foutd = app.Tedates_num(end-11:end,:);
%             Foutd
%             
%             return;
%             
%             app.YSeries
%             cols = val;%app.YSeries(val);
%             tot = find(strcmp(cols,'MonthlyTotalDemand'));
%             
%             Y = Fin(:,cols);
%             app.YSeries = Y.Properties.VariableNames;
%             Y = table2array(Y);
%             
%             nAR = 12; % Number of lags
%             % Spec = vgxset('n', numel(YSeries), 'Constant', true, 'nAR', nAR, 'Series', YSeries);
%             % Spec = vgxvarx(Spec, Y);
%             Spec = varm(numel(app.YSeries), nAR);
%             Spec = estimate(Spec, Y);
%             
%             Horizon = 12; % Number of quarters
%             NumPaths = 10000; % Number of Simulations
%             % FY = vgxsim(Spec, Horizon, [], Y, [], NumPaths);
%             FY = simulate(Spec, Horizon);
%             % FY = vgxpred(Spec, Horizon, [], Y, [], NumPaths);
%             
%             TD = squeeze(FY(:,tot,:)); %Total Demand
%             mean_sim = mean(TD,2);
%             std_sim = std(TD,[],2);
%             
%           %  hold(app.UIAxes)
%             plot(app.UIAxes, Find,Y(:,1));
%             dynamicDateTicks
%             x = length(Y(:,1))+ [1:Horizon];
%             hold(app.UIAxes)
%             plot(app.UIAxes, Foutd,mean_sim,'g')
%             plot(app.UIAxes, Foutd,mean_sim + 1.96*std_sim, 'r:', 'lineWidth', 2)
%             plot(app.UIAxes, Foutd,mean_sim - 1.96*std_sim, 'r:', 'lineWidth', 2)
%             dynamicDateTicks
%             Yout = table2array(Fout(:,cols));
%             plot(app.UIAxes, Foutd,Yout(:,1),'m')
%             legend(app.UIAxes, char(cols),'forecast','+5%','-5%','actual','Location','Best')
%             
%             error = mean_sim - Yout(:,1);
%             errorpct =abs(error)./Yout(:,1)*100;
%             MAE = mean(abs(error));
%             MAPE = mean(errorpct(~isinf(errorpct)));
%    
%             title(app.UIAxes, ['Energy Forecast - Mean Error Pct = ',num2str(MAPE),'%'])
%             xlabel(app.UIAxes,'Date')
%             ylabel(app.UIAxes, 'Total Monthly Energy Demand')           
        end

        % Value changed function: PasswordEditField
        function PasswordEditFieldValueChanged(app, event)
            value = app.PasswordEditField.Value;
            if(value == "beethoven")
                app.VectorautoregressionmodelVARPanel.Visible = 'on';
            end 
        end

        % Button pushed function: BacktestforVARMButton
        function BacktestforVARMButtonPushed(app, event)
            
            
            cla(app.UIAxes,'reset') % deletes graphics objects from the axes or polar axes specified by ax instead of the current axes
            FeatureList = app.FeaturesListBox.Value; %obtains the feature list from the global feature list (app.Featurelist)
            FeatureList = [{'RETURNT1'} , FeatureList]; %Adds the predictive label to the featurelist
                       
            %Creates a timetable with our old mutual data
            Y = app.TEcon(:,FeatureList); %Grabs all of the columns in the data specified by featurelist
            app.YSeries = Y.Properties.VariableNames;
            ActualData = table2array(Y);          
            Data = array2timetable(ActualData,'RowTimes', datetime(app.TEcon.DateStamps), 'VariableNames', FeatureList);
            
            numberofMonths = app.NoForecastPeriodsEditField.Value;
            numperiods = app.NoForecastPeriodsEditField.Value;
            Error = zeros(height(Data), 1);
            startoff  = app.StartoffEditField.Value;
            for i = startoff:1:height(Data)   
                %Create training and testing data
                DataSubset = Data(1:i,:);                         
                bfh = DataSubset.Time(i - numberofMonths);%sets a date (bfh - 31-Jul-2017) to partition the data in to training and testing              
                estIdx = DataSubset.Time < bfh; % creates a logical array marking all dates prior to bfh (i.e. [1 1 1 1 ... 1 1 1 1 1 1 0 0 0 0 0 ... 0 0 ])               

                %Create a Vector autoregression (VAR) model (varm) and a Fit vector autoregression (VAR) model to data (estimate)
                pvalue = app.pvalueEditField.Value;
                Mdl = varm(length(FeatureList),pvalue); %number of time series (response-variable dimensionality)  = length(FeatureList); AND the order of the multivariate autoregressive polynomial, which is denoted p = 4
                EstMdl = estimate(Mdl,DataSubset(estIdx,:).Variables); %Data(estIdx,:).Variables means that we only looking at the values of the variables and not the time-table part
                %summarize(EstMdl);
                            
                %Takes the VAR(p) model and forcasts (Forecast identified model output) over a number of periods            
                %numperiods = 12;
                [Y,YMSE] = forecast(EstMdl,numperiods,DataSubset(estIdx,:).Variables); % Y = Forecasted response, returned as an iddata object        YMSE = Estimated initial states at the start of forecasting, returned as a column vector of size equal to the number of states. 
               
                %Extract the main diagonal elements from the matrices in each cell of ForecastMSE. 
                extractMSE = @(x)diag(x)';
                MSE = cellfun(extractMSE,YMSE,'UniformOutput',false);
                SE = sqrt(cell2mat(MSE));%Apply the square root of the result to obtain standard errors.
                
                %Estimate approximate 95% forecast intervals for each response series.
                YFI = zeros(numperiods,Mdl.NumSeries,2);            
                YFI(:,:,1) = Y - 2*SE;
                YFI(:,:,2) = Y + 2*SE;                
                
                %h1 = plot(app.UIAxes, Data.Time((end-49):end),Data.rcpi((end-49):end));
                Error(i, 1) = mean(SE(:,1));
            end
            
             hold(app.UIAxes)
             plot(app.UIAxes, Data.Time(startoff:end, 1),Error(startoff:end,1));
             title(app.UIAxes, 'Error');
             ylabel(app.UIAxes, 'Probability');
             xlabel(app.UIAxes, 'Year');
           
            %fill(app.UIAxes, [bfh app.UIAxes.XLim([2 2]) bfh], app.UIAxes.YLim([1 1 2 2]),'k',...
               % 'FaceAlpha',0.1,'EdgeColor','none');
            
            %legend(app.UIAxes, [h1 h2 h3],'True','Forecast','95% Forecast interval', 'Location','northwest');
        end

        % Button pushed function: EstimateUsingVARButton
        function EstimateUsingVARButtonPushed(app, event)
            cla(app.UIAxes,'reset') % deletes graphics objects from the axes or polar axes specified by ax instead of the current axes
            FeatureList = app.FeaturesListBox.Value; %obtains the feature list from the global feature list (app.Featurelist)
            FeatureList = [{'RETURNT1'} , FeatureList]; %Adds the predictive label to the featurelist
                       
            %Creates a timetable with our old mutual data
            Y = app.TEcon(:,FeatureList); %Grabs all of the columns in the data specified by featurelist
            app.YSeries = Y.Properties.VariableNames;
            ActualData = table2array(Y);          
            Data = array2timetable(ActualData,'RowTimes',datetime(app.TEcon.DateStamps), 'VariableNames', FeatureList);
            
                      
            bfh = Data.Time(end) - years(3);%sets a date (bfh - 31-Jul-2017) to partition the data in to training and testing  
            estIdx = Data.Time < bfh; % creates a logical array marking all dates prior to bfh (i.e. [1 1 1 1 ... 1 1 1 1 1 1 0 0 0 0 0 ... 0 0 ])
                        
            %Create a Vector autoregression (VAR) model (varm) and a Fit vector autoregression (VAR) model to data (estimate)
            pvalue = app.pvalueEditField.Value;
            Mdl = varm(length(FeatureList),pvalue); %number of time series (response-variable dimensionality)  = length(FeatureList); AND the order of the multivariate autoregressive polynomial, which is denoted p = 4
            EstMdl = estimate(Mdl,Data(estIdx,:).Variables); %Data(estIdx,:).Variables means that we only looking at the values of the variables and not the time-table part
            summarize(EstMdl);
                        
            %Takes the VAR(p) model and forcasts (Forecast identified model output) over a number of periods            
            numperiods = 36;
            [Y,YMSE] = forecast(EstMdl,numperiods,Data(estIdx,:).Variables); % Y = Forecasted response, returned as an iddata object        YMSE = Estimated initial states at the start of forecasting, returned as a column vector of size equal to the number of states. 
           
            %Extract the main diagonal elements from the matrices in each cell of ForecastMSE. 
            extractMSE = @(x)diag(x)';
            MSE = cellfun(extractMSE,YMSE,'UniformOutput',false);
            SE = sqrt(cell2mat(MSE));%Apply the square root of the result to obtain standard errors.
            
            %Estimate approximate 95% forecast intervals for each response series.
            YFI = zeros(numperiods,Mdl.NumSeries,2);            
            YFI(:,:,1) = Y - 2*SE;
            YFI(:,:,2) = Y + 2*SE;
            
            
            %h1 = plot(app.UIAxes, Data.Time((end-49):end),Data.rcpi((end-49):end));
            h1 = plot(app.UIAxes,Data.Time,Data.RETURNT1);
            hold(app.UIAxes)
            h2 = plot(app.UIAxes, Data.Time(~estIdx),Y(:,1));
            h3 = plot(app.UIAxes, Data.Time(~estIdx),YFI(:,1,1),'r-.');
            plot(app.UIAxes, Data.Time(~estIdx),YFI(:,1,2),'r-.');
            title(app.UIAxes, 'Forecasted Return for a Share');
            ylabel(app.UIAxes, 'Return value');
            xlabel(app.UIAxes, 'Year');
            
            fill(app.UIAxes, [bfh app.UIAxes.XLim([2 2]) bfh], app.UIAxes.YLim([1 1 2 2]),'k',...
                'FaceAlpha',0.1,'EdgeColor','none');
            
            legend(app.UIAxes, [h1 h2 h3],'True','Forecast','95% Forecast interval', 'Location','northwest');
            
        end

        % Callback function
        function ForecastUnconditionalResponseSeriesButtonPushed(app, event)
            rcpi = app.TEcon.RETURNT1;
            unrate = app.TEcon.CapitalTurnover;
            Data = array2timetable([rcpi unrate],'RowTimes',datetime(app.TEcon.DateStamps), 'VariableNames',{'rcpi','unrate'});
            
            %Plot the actual retirn over the Period           
            h1 = plot(app.UIAxes2, datetime(app.TEcon.DateStamps),app.TEcon.Returns);
            title(app.UIAxes2, 'Returns over a 10 Year Cycle');
            ylabel(app.UIAxes2, 'Return');
            xlabel(app.UIAxes2, 'Time');
            legend(app.UIAxes2, h1,'ReturnT1');
                        
            %rcpi = price2ret(DataTable.CPIAUCSL);
            %unrate = DataTable.UNRATE(2:end);
            Data = array2timetable([rcpi unrate],'RowTimes',datetime(app.TEcon.DateStamps), 'VariableNames',{'rcpi','unrate'});
            
            %Create a default VAR(4) model using the shorthand syntax.
            Mdl = varm(2,4);
            
            %Estimate the model using the entire data set.
            EstMdl = estimate(Mdl,Data.Variables);
            
            %EstMdl is a fully specified, estimated varm model object.
            %Forecast responses from the estimated model over a three-year horizon. Specify the entire data set as presample observations.
            
            numperiods = 12;
            Y0 = Data.Variables;
            Y = forecast(EstMdl,numperiods,Y0);
            
            %Y is a 12-by-2 matrix of forecasted responses. The first and second columns contain the simulated CPI growth rate and unemployment rate, respectively.
                        
            fh = dateshift(Data.Time(end),'end','quarter',1:12);
            
            %Plot the graphic
            h1 = plot(app.UIAxes3, Data.Time((end-49):end), Data.rcpi((end-49):end));
            hold(app.UIAxes3);
            h2 = plot(app.UIAxes3, fh,Y(:,1));
            title(app.UIAxes3, 'Forcasted Return');
            xlabel(app.UIAxes3, 'Year');
            %h = gca; replaced with app.UIAxes3;
            fill(app.UIAxes3, [Data.Time(end) fh([end end]) Data.Time(end)], app.UIAxes3.YLim([1 1 2 2]),'k', 'FaceAlpha',0.1,'EdgeColor','none');
            legend(app.UIAxes3, [h1 h2],'True','Forecast');
        end

        % Button pushed function: 
        % ImportProcessedNonStandardisedDataButton
        function ImportProcessedNonStandardisedDataButtonPushed(app, event)
            load ProcessedNonStandardised.mat
            OLDmutualData = ProcessedNonStandardised;
            
            %Change the date format
            formatOut = 'yyyy-mm-dd'; 
            OLDmutualData.DateStamps = datestr(datenum(num2str(OLDmutualData.DateStamps(1:height(OLDmutualData))),'yyyymmdd'),formatOut);
            app.TEcon = OLDmutualData;
            
            %Adds the ShareIDs/Ticker to the Share GUI dropbox
            row = unique(OLDmutualData.Ticker)';            
            app.ShareDropDown.Items = string(row);            
            app.ShareDropDown.Value = 'FBR'; % set a default value (chosen because it has the most amount of data)
        end

        % Button pushed function: FeatureListButton
        function FeatureListButtonPushed(app, event)
            
            %Filter out all the shares we care about
            %app.TEcon.Shares = num2str(app.TEcon.Shares);
                     
            
            rows =  app.TEcon.Ticker == app.ShareDropDown.Value;
            
            app.TEcon = app.TEcon(rows, app.TEcon.Properties.VariableNames);
            
            %remove the last row
            app.TEcon(end,:) = [];
            
            %Sort the rows by the date
            app.TEcon = sortrows(app.TEcon,1);
            
%             OLDmutualData.DateStamps = string(OLDmutualData.DateStamps);
%             for i = 1: height(OLDmutualData)
%                 myString = OLDmutualData.DateStamps(i);
%                 OLDmutualData.DateStamps(i) = extractBefore(myString,5);
%                 OLDmutualData.Shares(i) = extractBetween(myString,5,6);
%             end
%             
%             OLDmutualData.Properties.VariableNames{'Shares'} = 'Months';
%             OLDmutualData.Properties.VariableNames{'DateStamps'} = 'Years';
%             OLDmutualData.Months = double(OLDmutualData.Months);
%             OLDmutualData.Years = double(OLDmutualData.Years);
            
%             app.TEdates = [num2str(OLDmutualData.Months),repmat(',',length(OLDmutualData.Months),1),num2str(OLDmutualData.Years)];
            % TEdates = TEcon.DateStamps;
            %app.Tedates_num = datenum(app.TEdates,'mm,yyyy');
            
            %app.TEcon = OLDmutualData;
            
            % TEcon = [T,meantempmax,econ2];
            % TEcon(end,:) = []; % removing final row as there is incomplete monthly data
            % TEdates = [num2str(TEcon.Months),repmat(',',length(TEcon.Months),1),num2str(TEcon.Years)];
            % Tedates_num = datenum(TEdates,'mm,yyyy');
            % 
             app.YSeries = app.TEcon.Properties.VariableNames';
                                       
             %Remove first 3 columns in the data (remove from feature list)
             PresentFeatureSet = app.YSeries;
             PresentFeatureSet(1:5) = [];             
             app.FeaturesListBox.Items = PresentFeatureSet;
             app.ForecastingPanel.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VectorAutoregressionModel = uifigure;
            app.VectorAutoregressionModel.Name = 'Vector Autoregression Model';
            app.PasswordEditFieldLabel = uilabel(app.VectorAutoregressionModel);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.VectorAutoregressionModel, 'text');
            app.PasswordEditField.ValueChangedFcn = createCallbackFcn(app, @PasswordEditFieldValueChanged, true);
            app.VectorautoregressionmodelVARPanel = uipanel(app.VectorAutoregressionModel);
            app.VectorautoregressionmodelVARPanel.Title = 'Vector autoregression model (VAR)';
            app.FeaturesLabel = uilabel(app.VectorautoregressionmodelVARPanel);
            app.FeaturesLabel.Text = 'Features:';
            app.FeaturesListBox = uilistbox(app.VectorautoregressionmodelVARPanel);
            app.FeaturesListBox.Items = {};
            app.FeaturesListBox.Multiselect = 'on';
            app.FeaturesListBox.Value = {};
            app.UIAxes = uiaxes(app.VectorautoregressionmodelVARPanel);
            title(app.UIAxes, 'Graphic')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ForecastingPanel = uipanel(app.VectorautoregressionmodelVARPanel);
            app.ForecastingPanel.Title = 'Forecasting: ';
            app.ForcastButton = uibutton(app.ForecastingPanel, 'push');
            app.ForcastButton.ButtonPushedFcn = createCallbackFcn(app, @ForcastButtonPushed, true);
            app.ForcastButton.Enable = 'off';
            app.ForcastButton.Text = 'Forcast';
            app.BacktestforVARMButton = uibutton(app.ForecastingPanel, 'push');
            app.BacktestforVARMButton.ButtonPushedFcn = createCallbackFcn(app, @BacktestforVARMButtonPushed, true);
            app.BacktestforVARMButton.Text = 'Back test for VARM';
            app.EstimateUsingVARButton = uibutton(app.ForecastingPanel, 'push');
            app.EstimateUsingVARButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateUsingVARButtonPushed, true);
            app.EstimateUsingVARButton.Text = ' Estimate Using VAR';
            app.pvalueEditFieldLabel = uilabel(app.ForecastingPanel);
            app.pvalueEditFieldLabel.Text = 'p-value';
            app.pvalueEditField = uieditfield(app.ForecastingPanel, 'numeric');
            app.pvalueEditField.Value = 4;
            app.Label = uilabel(app.ForecastingPanel);
            app.Label.Text = '*';
            app.NoForecastPeriodsEditFieldLabel = uilabel(app.ForecastingPanel);
            app.NoForecastPeriodsEditFieldLabel.Text = 'No. Forecast Periods';
            app.NoForecastPeriodsEditField = uieditfield(app.ForecastingPanel, 'numeric');
            app.NoForecastPeriodsEditField.Value = 12;
            app.StartoffEditFieldLabel = uilabel(app.ForecastingPanel);
            app.StartoffEditFieldLabel.Text = 'Startoff';
            app.StartoffEditField = uieditfield(app.ForecastingPanel, 'numeric');
            app.StartoffEditField.Value = 40;
            app.SlidingWindowCheckBox = uicheckbox(app.ForecastingPanel);
            app.SlidingWindowCheckBox.Text = 'Sliding Window';
            app.EditField = uieditfield(app.ForecastingPanel, 'numeric');
            app.CalculatingErrorsLabel = uilabel(app.ForecastingPanel);
            app.CalculatingErrorsLabel.Text = 'Calculating Errors';
            app.ImportProcessedStandardisedDataButton = uibutton(app.VectorautoregressionmodelVARPanel, 'push');
            app.ImportProcessedStandardisedDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportProcessedStandardisedDataButtonPushed, true);
            app.ImportProcessedStandardisedDataButton.Text = 'Import Processed Standardised Data';
            app.ImportProcessedNonStandardisedDataButton = uibutton(app.VectorautoregressionmodelVARPanel, 'push');
            app.ImportProcessedNonStandardisedDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportProcessedNonStandardisedDataButtonPushed, true);
            app.ImportProcessedNonStandardisedDataButton.Text = 'Import Processed Non-Standardised Data';
            app.ShareDropDownLabel = uilabel(app.VectorautoregressionmodelVARPanel);
            app.ShareDropDownLabel.Text = 'Share';
            app.ShareDropDown = uidropdown(app.VectorautoregressionmodelVARPanel);
            app.ShareDropDown.Items = {};
            app.ShareDropDown.Value = {};
            app.FeatureListButton = uibutton(app.VectorautoregressionmodelVARPanel, 'push');
            app.FeatureListButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureListButtonPushed, true);
            app.FeatureListButton.Text = 'Feature List';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VectorAutoregression

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VectorAutoregressionModel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VectorAutoregressionModel)
        end
    end
end